classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InputTypeDropDownLabel      matlab.ui.control.Label
        InputTypeDropDown           matlab.ui.control.DropDown
        OrderLabel                  matlab.ui.control.Label
        Order                       matlab.ui.control.Spinner
        OpenFileBtn                 matlab.ui.control.Button
        RecordBtn                   matlab.ui.control.Button
        PassBandEdgeLabel           matlab.ui.control.Label
        PassBandEdge                matlab.ui.control.NumericEditField
        StopBandEdgeLabel           matlab.ui.control.Label
        StopBandEdge                matlab.ui.control.NumericEditField
        MinStopBandAttLabel         matlab.ui.control.Label
        MinimumStopBandAttenuation  matlab.ui.control.NumericEditField
        MinStopBandAttLabel_2       matlab.ui.control.Label
        MaximumPassBandAttenuation  matlab.ui.control.NumericEditField
        ExecuteButton               matlab.ui.control.Button
        Window                      matlab.ui.container.ButtonGroup
        HammingButton               matlab.ui.control.RadioButton
        BlackmanButton              matlab.ui.control.RadioButton
        Button                      matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        recorder % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputTypeDropDown
        function InputTypeDropDownValueChanged(app, event)
            value = app.InputTypeDropDown.Value;
            if(value == "Image")
                app.TypeDropDown_2.Visible = false;
                app.TypeDropDown_2Label.Visible = false;
            else
                app.TypeDropDown_2.Visible = true;
                app.TypeDropDown_2Label.Visible = true;
            end
        end

        % Button pushed function: RecordBtn
        function RecordBtnButtonPushed(app, event)
            if(app.RecordBtn.Text == "Record")
                app.recorder = audiorecorder;
                app.recorder.record();
                app.RecordBtn.Text = "Stop";
            else
                app.recorder.stop();
                app.RecordBtn.Text = "Record";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputTypeDropDownLabel = uilabel(app.UIFigure);
            app.InputTypeDropDownLabel.Text = 'Input Type';
            app.InputTypeDropDown = uidropdown(app.UIFigure);
            app.InputTypeDropDown.Items = {'Speech', 'Image'};
            app.InputTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InputTypeDropDownValueChanged, true);
            app.InputTypeDropDown.Value = 'Speech';
            app.OrderLabel = uilabel(app.UIFigure);
            app.OrderLabel.Text = 'Order';
            app.Order = uispinner(app.UIFigure);
            app.OpenFileBtn = uibutton(app.UIFigure, 'push');
            app.OpenFileBtn.Text = 'Choose File';
            app.RecordBtn = uibutton(app.UIFigure, 'push');
            app.RecordBtn.ButtonPushedFcn = createCallbackFcn(app, @RecordBtnButtonPushed, true);
            app.RecordBtn.Text = 'Record';
            app.PassBandEdgeLabel = uilabel(app.UIFigure);
            app.PassBandEdgeLabel.Text = 'Pass Band Edge';
            app.PassBandEdge = uieditfield(app.UIFigure, 'numeric');
            app.StopBandEdgeLabel = uilabel(app.UIFigure);
            app.StopBandEdgeLabel.Text = 'Stop Band Edge';
            app.StopBandEdge = uieditfield(app.UIFigure, 'numeric');
            app.MinStopBandAttLabel = uilabel(app.UIFigure);
            app.MinStopBandAttLabel.Text = 'Min Stop Band Att.';
            app.MinimumStopBandAttenuation = uieditfield(app.UIFigure, 'numeric');
            app.MinStopBandAttLabel_2 = uilabel(app.UIFigure);
            app.MinStopBandAttLabel_2.Text = 'Min Stop Band Att.';
            app.MaximumPassBandAttenuation = uieditfield(app.UIFigure, 'numeric');
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.Text = 'Execute';
            app.Window = uibuttongroup(app.UIFigure);
            app.Window.Title = 'Window';
            app.HammingButton = uiradiobutton(app.Window);
            app.HammingButton.Text = 'Hamming';
            app.HammingButton.Value = true;
            app.BlackmanButton = uiradiobutton(app.Window);
            app.BlackmanButton.Text = 'Blackman';
            app.Button = uiradiobutton(app.Window);
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end